classdef task1core_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DeleteTaskButton      matlab.ui.control.Button
        AddtaskButton         matlab.ui.control.Button
        ValidateandRunButton  matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = {'' , 0, 0};
            app.UITable.ColumnEditable = [true, true, true];
        end

        % Button pushed function: ValidateandRunButton
        function ValidateandRunButtonPushed(app, event)
            % get user input
                data = app.UITable.Data;

            % convert data to numbers
                C = cell2mat(data(:,2)); 
                T = cell2mat(data(:,3));

            % calculate utilization
                utilization = sum(C ./ T);

            % user feedback
                if utilization > 1.0
                    uialert(app.UIFigure, 'workload too heavy for 1 core', 'Overload');
                else
                uialert(app.UIFigure, ['Total CPU Load: ', num2str(utilization*100), '%'], 'Success');

                numericData = cell2mat(app.UITable.Data(:, 2:3));
                assignin('base', 'task_table_data', numericData);
                
                open_system('core1_simulator_v2'); 
                set_param('core1_simulator_v2', 'SimulationCommand', 'start');

                end
               
        end

        % Button pushed function: AddtaskButton
        function AddtaskButtonPushed(app, event)
            currentData = app.UITable.Data;
  
            newRow = {'' , 0, 0};
    
            app.UITable.Data = [currentData; newRow];
        end

        % Button pushed function: DeleteTaskButton
        function DeleteTaskButtonPushed(app, event)
            currentData = app.UITable.Data;
   
            selectedCell = app.UITable.Selection;
    
            if isempty(selectedCell)
                uialert(app.UIFigure, 'Select task', 'No Selection');
            return;
            end
            
            rowToDelete = selectedCell(1);
    
            currentData(rowToDelete, :) = [];
            
            app.UITable.Data = currentData;
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Task Name'; 'Execution Time (ms)'; 'Period (ms)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.ValidateandRunButton = uibutton(app.UIFigure, 'push');
            app.ValidateandRunButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateandRunButtonPushed, true);
            app.ValidateandRunButton.Text = 'Validate and Run';
            app.AddtaskButton = uibutton(app.UIFigure, 'push');
            app.AddtaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddtaskButtonPushed, true);
            app.AddtaskButton.Text = 'Add task';
            app.DeleteTaskButton = uibutton(app.UIFigure, 'push');
            app.DeleteTaskButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTaskButtonPushed, true);
            app.DeleteTaskButton.Text = 'Delete Task';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1core_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end